classdef Vital < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BatteryVoltageVoltPanel  matlab.ui.container.Panel
        MinLabel                 matlab.ui.control.Label
        AverageLabel             matlab.ui.control.Label
        MaxLabel                 matlab.ui.control.Label
        Vmax                     matlab.ui.control.Label
        Vmoy                     matlab.ui.control.Label
        Vmin                     matlab.ui.control.Label
        WaterTemperaturecPanel   matlab.ui.container.Panel
        MaxLabel_2               matlab.ui.control.Label
        AverageLabel_2           matlab.ui.control.Label
        TwaterMax                matlab.ui.control.Label
        TwaterMoy                matlab.ui.control.Label
        AirinfoPressureinbartempincPanel  matlab.ui.container.Panel
        PressuremaxLabel         matlab.ui.control.Label
        AveragetemperaureLabel   matlab.ui.control.Label
        PairMax                  matlab.ui.control.Label
        TairMoy                  matlab.ui.control.Label
        signalButtonGroup        matlab.ui.container.ButtonGroup
        RPMButton                matlab.ui.control.RadioButton
        WaterTempButton          matlab.ui.control.RadioButton
        OilPressureButton        matlab.ui.control.RadioButton
        AirPressureButton        matlab.ui.control.RadioButton
        AirTemperatureButton     matlab.ui.control.RadioButton
        BatteryVoltageButton     matlab.ui.control.RadioButton
        OilPressurePanel         matlab.ui.container.Panel
        PressuremaxLabel_2       matlab.ui.control.Label
        PoilMax                  matlab.ui.control.Label
        Poilmoy                  matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
    end


    properties (Access = private)
        Callingapp % Description
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Callingapp = Mainapp;
            
            %battery
            app.Vmax.Text = int2str(max(app.Callingapp.Vbat));
            app.Vmoy.Text = int2str(mean(app.Callingapp.Vbat,'native'));
            app.Vmin.Text = int2str(min(app.Callingapp.Vbat));
            
            %water
            app.TwaterMax.Text = int2str(max(app.Callingapp.Twater)); 
            for k=1:lentgh(app.Callingapp.Twater)
                if RPM(k)>100
                    L(k) = app.Callingapp.Twater;
                end
            end
            
            app.Tairmoy.Text = int2str(mean(L,'native'));
            
            %Air
            app.PairMax.Text = int2str(max(app.Callingapp.Pair));
            app.Tairmoy.Text = int2str(mean(app.Callingapp.Tair,'native'));
            
            %oil
            app.Poilmax.Text = int2str(max(app.Callingapp.Poil)); 
            for k=1:lentgh(app.Callingapp.Poil)
                if RPM(k)>100
                    L(k) = app.Callingapp.Poil;
                end
            end
            app.Tairmoy.Text = int2str(mean(L,'native'));
            
            %check box
            app.RPMButton.Value = 1;
            app.WaterTempButton.Value = 1;
            app.OilPressureButton.Value = 1;
            app.AirTemperatureButton.Value = 1;
            app.AirPressureButton.Value = 1;
            app.BatteryVoltageButton = 1;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            n = app.RPMButton.Value + app.WaterTempButton.Value + app.OilPressureButton.Value + app.AirTemperatureButton.Value + app.AirPressureButton.Value + app.BatteryVoltageButton;
            A=[0 0 0 0 0];
            if app.RPMButton.Value
                rpm =subplot(n,5,[ 1 2 3 4 5])
                title(rpm,'RPM')
                plot(rpm,app.Callingapp.Tcan,app.Callingapp.RPM)
                A(1)=1;
            end
            if app.WaterTempButton.Value
                Twater=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5])
                title(Twater,'Water Temperrature')
                plot(Twater,app.Callingapp.Tcan,app.Callingapp.Twater)
                A(2)=1
            end
            if app.OilPressureButton.Value
                poil=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5])
                title(poil,'Oil Pressure')
                plot(poil,app.Callingapp.Tcan,app.Callingapp.Poil)
                A(3)=1
            end
            if app.AirTemperatureButton.Value
                Tair=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5])
                title(Tair,'Air Temperature')
                plot(Tair,app.Callingapp.Tcan,app.Callingapp.Tair)
                A(4)=1
            end
            if app.AirPressureButton.Value
                Pair=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5])
                title(Pair,'Air Pressure')
                plot(Pair,app.Callingapp.Tcan,app.Callingapp.Pair)
                A(5)=1
            end
            if app.BatteryVoltageButton.Value
                BV=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5])
                title(BV,'Air Temperature')
                plot(BV,app.Callingapp.Tcan,app.Callingapp.Vbat)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BatteryVoltageVoltPanel = uipanel(app.UIFigure);
            app.BatteryVoltageVoltPanel.Title = 'Battery Voltage (Volt)';
            app.MinLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.MinLabel.Text = 'Min :';
            app.AverageLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.AverageLabel.Text = 'Average :';
            app.MaxLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.MaxLabel.Text = 'Max :';
            app.Vmax = uilabel(app.BatteryVoltageVoltPanel);
            app.Vmoy = uilabel(app.BatteryVoltageVoltPanel);
            app.Vmin = uilabel(app.BatteryVoltageVoltPanel);
            app.WaterTemperaturecPanel = uipanel(app.UIFigure);
            app.WaterTemperaturecPanel.Title = 'Water Temperature (°c)';
            app.MaxLabel_2 = uilabel(app.WaterTemperaturecPanel);
            app.MaxLabel_2.Text = 'Max :';
            app.AverageLabel_2 = uilabel(app.WaterTemperaturecPanel);
            app.AverageLabel_2.Text = 'Average :';
            app.TwaterMax = uilabel(app.WaterTemperaturecPanel);
            app.TwaterMoy = uilabel(app.WaterTemperaturecPanel);
            app.AirinfoPressureinbartempincPanel = uipanel(app.UIFigure);
            app.AirinfoPressureinbartempincPanel.Title = 'Air info (Pressure in bar, temp in °c)';
            app.PressuremaxLabel = uilabel(app.AirinfoPressureinbartempincPanel);
            app.PressuremaxLabel.Text = 'Pressure max :';
            app.AveragetemperaureLabel = uilabel(app.AirinfoPressureinbartempincPanel);
            app.AveragetemperaureLabel.Text = 'Average temperaure :';
            app.PairMax = uilabel(app.AirinfoPressureinbartempincPanel);
            app.TairMoy = uilabel(app.AirinfoPressureinbartempincPanel);
            app.signalButtonGroup = uibuttongroup(app.UIFigure);
            app.signalButtonGroup.Title = 'signal';
            app.RPMButton = uiradiobutton(app.signalButtonGroup);
            app.RPMButton.Text = 'RPM';
            app.RPMButton.Value = true;
            app.WaterTempButton = uiradiobutton(app.signalButtonGroup);
            app.WaterTempButton.Text = 'Water Temp';
            app.OilPressureButton = uiradiobutton(app.signalButtonGroup);
            app.OilPressureButton.Text = 'Oil Pressure';
            app.AirPressureButton = uiradiobutton(app.signalButtonGroup);
            app.AirPressureButton.Text = 'Air Pressur';
            app.AirTemperatureButton = uiradiobutton(app.signalButtonGroup);
            app.AirTemperatureButton.Text = 'Air Temperature';
            app.BatteryVoltageButton = uiradiobutton(app.signalButtonGroup);
            app.BatteryVoltageButton.Text = 'Battery Voltage';
            app.OilPressurePanel = uipanel(app.UIFigure);
            app.OilPressurePanel.Title = 'Oil Pressure';
            app.PressuremaxLabel_2 = uilabel(app.OilPressurePanel);
            app.PressuremaxLabel_2.Text = 'Pressure max :';
            app.PoilMax = uilabel(app.OilPressurePanel);
            app.Poilmoy = uilabel(app.OilPressurePanel);
            app.Poilmoy.Text = {'Average Pressure while'; 'motor running:'};
            app.Label_9 = uilabel(app.OilPressurePanel);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Vital(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end