classdef datavisu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DataMenu                    matlab.ui.container.Menu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
        Menu3                       matlab.ui.container.Menu
        GPS                         matlab.ui.control.UIAxes
        NomdufichierexcelLabel_2    matlab.ui.control.Label
        excel                       matlab.ui.control.EditField
        NomdelapageconcerneLabel_2  matlab.ui.control.Label
        page                        matlab.ui.control.EditField
        ImporterButton              matlab.ui.control.Button
        Duree                       matlab.ui.control.Label
        Vmax                        matlab.ui.control.Label
        Gxmax                       matlab.ui.control.Label
        Gxmin                       matlab.ui.control.Label
        Gymax                       matlab.ui.control.Label
        Logo                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Time %en s
        Tacc %en s
        Tgps %en s
        X %en M
        Y %en m
        AccelX %en G
        AccelY %en G
        AccelZ %en G
        Yaw %en deg/s
        Pitch %en deg/s
        Roll %en deg/s
        Latitude %en rad
        Longitude %en rad
        Speed %en rad
        Altitude %en m
    end

    methods (Access = private)    
       function [Ls, Ts] = supnan(app,L,T)
            %fonction qui supprime les 100000 et qui créé une liste avec les temps
            %associé au mesures
            Ls=[];
            Ts=[];
            for i=1:length(L)
                if L(i)==100000
                else
                    Ls = [Ls L(i)];
                    Ts = [Ts T(i)];
                end
            end
       end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            logo = imread('logo.png');
            imshow(logo,'Parent',app.Logo);
            axis(app.Logo, 'off');
        end

        % Button pushed function: ImporterButton
        function ImporterButtonPushed(app, event)

            % Import the data
            [~, ~, raw] = xlsread(app.excel.Value,app.page.Value);
            raw(cellfun(@(x) ~isempty(x) && isnumeric(x) && isnan(x),raw)) = {''}; 
            
            % Replace non-numeric cells with 100000
            R = cellfun(@(x) ~isnumeric(x) && ~islogical(x),raw); % Find non-numeric cells
            raw(R) = {100000}; % Replace non-numeric cells
            
            % Create output variable
            data = reshape([raw{:}],size(raw));
            
            
            % Allocate imported array to column variable names
            app.Time = (data(:,1)-data(1,1))/1000; %en seconde et remis à zeros
            [app.AccelX, app.Tacc ]= supnan(app,data(:,2),app.Time); %en G
            app.AccelY = supnan(app,data(:,3),app.Time); %en G
            app.AccelZ= supnan(app,data(:,4),app.Time); %en G
            app.Yaw = supnan(app,data(:,5),app.Time); %en deg/s
            app.Pitch = supnan(app,data(:,6),app.Time); %en deg/s
            app.Roll = supnan(app,data(:,7),app.Time); %en deg/s
            [app.Latitude, app.Tgps] = supnan(app,data(:,8),app.Time);
            app.Latitude = app.Latitude(80:length(app.Latitude));
            app.Latitude = (app.Latitude-app.Latitude(1))*pi/180; %en radian
            app.Longitude = supnan(app,data(:,9),app.Time);
            app.Longitude = app.Longitude(80:length(app.Longitude)); 
            app.Longitude = (app.Longitude-app.Longitude(1))*pi/180; %en radian
            app.Speed = supnan(app,data(:,10),app.Time)*1.60934; %en km/h
            app.Speed = app.Speed(80:length(app.Speed));

            % Clear temporary variables
            clearvars data raw R;
            
            %file informations
            
            %Duree
            s = mod(round(max(app.Time)),60);
            m = ( round(max(app.Time))-mod(round(max(app.Time)),60))/60;
            app.Duree.Text = strcat('Durée du Run : ',' ',num2str(m),' min ',num2str(s),' s');       
            %Vmax
            app.Vmax.Text = strcat('Vitesse maximale : ',' ',num2str(round(max(app.Speed))),' km/h');
            %Gx max
            app.Gxmax.Text = strcat('Accélération longitudinale maximale :',' ',num2str(max(app.AccelX)),' g');   
            %Gx min
            app.Gxmin.Text = strcat('Accélération longitudinale minimale :',' ',num2str(min(app.AccelX)),' g');
            %Gy max
            app.Gymax.Text = strcat('Accélération latérale maximale :',' ',num2str(max(abs(app.AccelY))),' g');
            
            %GPS 
            R = 6378137;

            app.X = R .* app.Latitude;
            app.Y = R .* app.Longitude;
            plot(app.GPS,app.X,app.Y)
            axis(app.GPS,[min(app.X)-10,max(app.X)+10,min(app.Y)-10,max(app.Y)+10]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.Menu = uimenu(app.DataMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.UIFigure);
            app.Menu3.Text = 'Menu3';
            app.GPS = uiaxes(app.UIFigure);
            title(app.GPS, 'Coordonnées GPS')
            app.NomdufichierexcelLabel_2 = uilabel(app.UIFigure);
            app.NomdufichierexcelLabel_2.Text = 'Nom du fichier excel :';
            app.excel = uieditfield(app.UIFigure, 'text');
            app.NomdelapageconcerneLabel_2 = uilabel(app.UIFigure);
            app.NomdelapageconcerneLabel_2.Text = 'Nom de la page concernée :';
            app.page = uieditfield(app.UIFigure, 'text');
            app.ImporterButton = uibutton(app.UIFigure, 'push');
            app.ImporterButton.ButtonPushedFcn = createCallbackFcn(app, @ImporterButtonPushed, true);
            app.ImporterButton.Text = 'Importer';
            app.Duree = uilabel(app.UIFigure);
            app.Duree.Text = 'Durée du Run : ';
            app.Vmax = uilabel(app.UIFigure);
            app.Vmax.Text = 'Vitesse maximale :';
            app.Gxmax = uilabel(app.UIFigure);
            app.Gxmax.Text = 'Accélération longitudinale maximale :';
            app.Gxmin = uilabel(app.UIFigure);
            app.Gxmin.Text = 'Accélération longitudinale minimale :';
            app.Gymax = uilabel(app.UIFigure);
            app.Gymax.Text = 'Accélération latérale maximale :';
            app.Logo = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = datavisu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end