classdef XY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        XYplot          matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
        YDropDownLabel  matlab.ui.control.Label
        YDropDown       matlab.ui.control.DropDown
        XYPlotLabel     matlab.ui.control.Label
        XDropDownLabel  matlab.ui.control.Label
        XDropDown       matlab.ui.control.DropDown
        PlotButton      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Datavisu
        Yindex %index des données à afficher en Y
        Xindex %index des données à afficher en X
        Ydata %Données à afficher en Y
        Xdata %Données à afficher en X
        YLabel %Nom des données en Y
        XLabel %Nom des données en X
        data_struct

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.XYplot.Name = 'X-Y plot';
            app.CallingApp = Mainapp;
            app.data_struct = app.CallingApp.data_struct;
            assignin('base','struct',app.data_struct);
            datalist=sort({app.data_struct.Name}); %utilisation de sort pour trier par ordre alphabétique
            app.YDropDown.Items = datalist;
            app.XDropDown.Items = datalist;
            
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            value = app.YDropDown.Value;
            app.Yindex = get_data_index(app.data_struct, value);
            app.Ydata = app.data_struct(app.Yindex).Data;
            ylabel(app.UIAxes,strcat(app.YDropDown.Value,{' in '},app.data_struct(app.Yindex).Unit));
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            value = app.XDropDown.Value;
            app.Xindex = get_data_index(app.data_struct, value);
            app.Xdata = app.data_struct(app.Xindex).Data;
            xlabel(app.UIAxes,strcat(app.XDropDown.Value,{' in '},app.data_struct(app.Xindex).Unit));           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            grid(app.UIAxes,'on')
            xlabel(app.UIAxes,strcat(app.XDropDown.Value,{' in '},app.data_struct(app.Xindex).Unit));
            ylabel(app.UIAxes,strcat(app.YDropDown.Value,{' in '},app.data_struct(app.Yindex).Unit));
            plot(app.UIAxes, app.Xdata, app.Ydata,'*');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.XYplot.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {620, 620};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {31, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.XYplot = uifigure('Visible', 'off');
            app.XYplot.Name = 'UI Figure';
            app.XYplot.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.XYplot);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'X-Y plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.YDropDownLabel = uilabel(app.RightPanel);
            app.YDropDownLabel.Text = 'Y :';
            app.YDropDown = uidropdown(app.RightPanel);
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.XYPlotLabel = uilabel(app.RightPanel);
            app.XYPlotLabel.Text = 'X-Y Plot';
            app.XDropDownLabel = uilabel(app.RightPanel);
            app.XDropDownLabel.Text = 'X :';
            app.XDropDown = uidropdown(app.RightPanel);
            app.XDropDown.Items = {};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.XDropDown.Value = {};
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.XYplot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XY(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.XYplot)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.XYplot)
        end
    end
end