classdef datavisu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataVisu                        matlab.ui.Figure
        DisplayMenu                     matlab.ui.container.Menu
        tempsdistanceMenu               matlab.ui.container.Menu
        DisplayontrackMenu              matlab.ui.container.Menu
        XYplotMenu                      matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        GForces                         matlab.ui.container.Menu
        CorneringMenu                   matlab.ui.container.Menu
        VitalFunctionsMenu              matlab.ui.container.Menu
        BrakingMenu                     matlab.ui.container.Menu
        GripfactorsMenu                 matlab.ui.container.Menu
        DampersMenu                     matlab.ui.container.Menu
        RollMenu                        matlab.ui.container.Menu
        FuelcomsuptionMenu              matlab.ui.container.Menu
        PiloteMenu                      matlab.ui.container.Menu
        LapTimesMenu                    matlab.ui.container.Menu
        ImportafilePanel                matlab.ui.container.Panel
        ImportButton                    matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        InProgress                      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        NbTour                          matlab.ui.control.Label
        Ttour                           matlab.ui.control.Label
        ExportdatatotheworkspaceButton  matlab.ui.control.Button
        Gymax                           matlab.ui.control.Label
        Gxmin                           matlab.ui.control.Label
        Gxmax                           matlab.ui.control.Label
        Vmax                            matlab.ui.control.Label
        Duree                           matlab.ui.control.Label
        GPS                             matlab.ui.control.UIAxes
    end


    properties (Access = public) 
        data_struct %Sructure contenant toute les données
    end

    properties (Access = private)
        filename
        
        appCornering %application cornering
        Plot_app %application plot
        appG_force %application Gforces
        appVital %application vital functions
        appBraking
        appDampers
        appTemps
        appGrip
        appRoll
        appTrackplot
        appXY
        appFC
    end


    methods (Access = private)
        function [X,Y] = lat_longi2X_Y(~, lattitude, longitude)
            % lattitude et longitude sont les données brut en ° venant des logs
            lat_red = (lattitude - mean(lattitude))*pi/180;
            long_red = (longitude - mean(longitude))*pi/180;

            R = 6378137; % Rayon standard de la terre

            X = -R .* tan(lat_red);
            Y = R .* tan(long_red);
        end
    
        function [] = track_plot(app, X, Y, speed)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h
            cla(app.GPS,'reset') % reset le graph
            z = zeros(size(X'));
            col = speed';  % This is the color, vary with x in this case.
            surface(app.GPS, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','flat','linew',2);
            c = colorbar(app.GPS);
            xlabel(app.GPS, 'X (m)')
            ylabel(app.GPS, 'Y (m)')
            c.Label.String = 'Vitesse Km/h';
            axis(app.GPS,[min(X)-10,max(X)+10,min(Y)-10,max(Y)+10]);
            set(app.GPS,'Visible','off')
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataVisu.Name = 'DataVisu';
            app.InProgress.Text = 'Waiting for a file';
            app.ImportButton.Enable = 'off';
            app.ExportdatatotheworkspaceButton.Visible = 'off';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Display
            app.InProgress.Text = strcat('importing',{' '},app.filename,'...');
            app.ImportButton.Enable = 'off';
            
            pause(0.01)          

            %Génération d'un Structure Array pour stocker les données
            app.data_struct = log2struct(app.filename);
                
            %------Conversion des données----------
            idTime = get_data_index(app.data_struct,'Interval');
            app.data_struct(idTime).Data = app.data_struct(idTime).Data/1000;
            app.data_struct(idTime).Unit='s';
            %remise à 0 des temps
            id_F5 = get_data_index(app.data_struct,'F5');
            id_F10 = get_data_index(app.data_struct,'F10');
            id_F50 = get_data_index(app.data_struct,'F50');
            
            app.data_struct(id_F5).Data  = app.data_struct(id_F5).Data - app.data_struct(idTime).Data(1);
            app.data_struct(id_F10).Data  = app.data_struct(id_F10).Data - app.data_struct(idTime).Data(1);
            app.data_struct(id_F50).Data  = app.data_struct(id_F50).Data - app.data_struct(idTime).Data(1);
            app.data_struct(idTime).Data  = app.data_struct(idTime).Data - app.data_struct(idTime).Data(1);
            
            
            %Changement de repère pour Optimus
            %Récupération des indexs des accel :
%             idaccX = get_data_index(app.data_struct,'AccelX');
%             idaccZ = get_data_index(app.data_struct,'AccelZ');
%             idRoll = get_data_index(app.data_struct,'Roll');
%             idYaw = get_data_index(app.data_struct,'Yaw');
%             Ar = deg2rad(127); % angle d'inclinaison du RaceCapture
%             R = [[cos(Ar)  sin(Ar)],
%                  [-sin(Ar) cos(Ar)]];
%             A = [app.data_struct(idaccX).Data, app.data_struct(idaccZ).Data]*R;
%             app.data_struct(idaccX).Data = A(:,1);
%             app.data_struct(idaccZ).Data = A(:,2);
%             B = [app.data_struct(idRoll).Data,app.data_struct(idYaw).Data]*R;
%             app.data_struct(idRoll).Data = B(:,1);
%             app.data_struct(idYaw).Data = B(:,2);
     
            %Gps
            
            idAlt = get_data_index(app.data_struct,'Altitude');
            app.data_struct(idAlt).Data = app.data_struct(idAlt).Data*0.3048; % en m
            
            idDist = get_data_index(app.data_struct,'Distance');
            app.data_struct(idDist).Data = app.data_struct(idDist).Data*1000; %en m
            
            
            idLapCount = get_data_index(app.data_struct,'LapCount');
            idLapTime = get_data_index(app.data_struct,'LapTime');
            app.data_struct(idLapTime).Data = app.data_struct(idLapTime).Data*60; %temps du tour précédent en s

            %------------CAN-----------------
            
            idBat = get_data_index(app.data_struct,'Batterie');  
            app.data_struct(idBat).Data = app.data_struct(idBat).Data; %en Volt
            
            %   Vitesse de roue
            
            idRL = get_data_index(app.data_struct,'LD_Speed');  
            app.data_struct(idRL).Data = app.data_struct(idRL).Data/10; %in km/h
            idRR = get_data_index(app.data_struct,'RD_Speed');  
            app.data_struct(idRR).Data = app.data_struct(idRR).Data/10; %in km/h
            idFR = get_data_index(app.data_struct,'RUD_Speed');  
            app.data_struct(idFR).Data = app.data_struct(idFR).Data/10; %in km/h
            idFL = get_data_index(app.data_struct,'LUD_Speed');  
            app.data_struct(idFL).Data = app.data_struct(idFL).Data/10; %in km/h


            %Déplacement des capteurs de suspension en mm
                     
            idXFR = get_data_index(app.data_struct,'Height_RF'); 
            if ~isempty(idXFR)
                %Conversion  Volt vers longueur capteur
                if mean(app.data_struct(idXFR).Data)<150
                    p1 = [1 203]; 
                else
                    p1 = [1 -7];
                end
                app.data_struct(idXFR).Data = polyval(p1,app.data_struct(idXFR).Data); 
                idXFL = get_data_index(app.data_struct,'Height_LF');  
                app.data_struct(idXFL).Data = polyval(p1,app.data_struct(idXFL).Data); 
                idXRR = get_data_index(app.data_struct,'Height_RR');  
                app.data_struct(idXRR).Data = polyval(p1,app.data_struct(idXRR).Data); 
                idXRL = get_data_index(app.data_struct,'Height_LR');  
                app.data_struct(idXRL).Data = polyval(p1,app.data_struct(idXRL).Data); 
                
                %Conversion longueur capteur vers longueur suspension
                %liste avant
                capteurAV = 240:10:350;
                susAV = [169.44, 178.3, 187.98, 198.38, 209.45, 221.18, 233.55, 246.607, 260.42, 275.12, 291, 308.61];
                app.data_struct(idXFR).Data = interp1(capteurAV,susAV,app.data_struct(idXFR).Data,'linear','extrap'); 
                app.data_struct(idXFL).Data = interp1(capteurAV,susAV,app.data_struct(idXFL).Data,'linear','extrap'); 
                %liste arrière
                capteurAR = [210:10:270, 275];
                susAR = [163.76, 172.07, 181.1, 190.92, 201.7, 213.83, 228.67, 239.826];
                app.data_struct(idXRR).Data = interp1(capteurAR,susAR,app.data_struct(idXRR).Data,'linear','extrap'); 
                app.data_struct(idXRL).Data = interp1(capteurAR,susAR,app.data_struct(idXRL).Data,'linear','extrap'); 
            end

        
            %file informations
            % Création d'un vecteur TLap tel que TLap de i donne le temps
            % du tour i
            
            ElapsedTime = get_data(app.data_struct,'ElapsedTime');
            
            if (~isempty(idLapCount)) && (sum(ElapsedTime>0)>1)
                LapCount = get_data(app.data_struct,"LapCount"); %vecteur du numéro de tour à 10Hz
                TLap = zeros(max(LapCount)+1,1);
                for k=1:length(ElapsedTime)-1
                    if (LapCount(k)~=LapCount(k+1))
                        TLap(LapCount(k)+1) = ElapsedTime(k)*60;
                    end
                end
                
                idTlap = numel(app.data_struct)+1;
                app.data_struct(idTlap).Unit = 's';
                app.data_struct(idTlap).Name = 'TLap';
                app.data_struct(idTlap).Data = TLap;
            end         
            
            %Duree
            Time = get_data(app.data_struct,'Interval');
            s = mod(round(max(Time)),60); %secondes
            m = ( round(max(Time))-mod(round(max(Time)),60))/60; %minutes
            app.Duree.Text = strcat('Run duration  : ',{' '},num2str(m),' min ',num2str(s),' s');
            
            if (~isempty(idLapCount)) && (sum(ElapsedTime>0)>1)
            %Nb de tour
                L = get_data(app.data_struct,'LapCount');
                nb = L(end);
                app.NbTour.Text = strcat('Number of lap : ',{' '},num2str(nb));
                app.Ttour.Text = strcat('Best lap time :',{' '},num2str(min(TLap),2),' s'); 
            else
                app.NbTour.Text = strcat('pas dispo');
                app.Ttour.Text = strcat('pas dispo');
            end
            
            %Vmax
            app.Vmax.Text = strcat('Maximum speed : ',{' '},num2str(round(max(get_data(app.data_struct,'Speed')))),' km/h');
            %Gx max
            app.Gxmax.Text = strcat('Maximum longitudinal acceleration :',{' '},num2str(max(get_data(app.data_struct,'AccelX')),2),' g');   
            %Gx min
            app.Gxmin.Text = strcat('Minimum longitudinal acceleration :',{' '},num2str(min(get_data(app.data_struct,'AccelX')),2),' g');
            %Gy max
            app.Gymax.Text = strcat('Maximum lateral acceleration :',{' '},num2str(max(abs(get_data(app.data_struct,'AccelY'))),2),' g');
            
            [X, Y] = lat_longi2X_Y(app, get_data(app.data_struct,'Latitude'), get_data(app.data_struct,'Longitude'));
         
            track_plot(app, X, Y, get_data(app.data_struct,'Speed'))
            %Save X
            idX = numel(app.data_struct)+1;
            app.data_struct(idX).Unit = 'm';
            app.data_struct(idX).Name = 'X';
            app.data_struct(idX).Data = X;
            app.data_struct(idX).Frequency = '10'; 
            
            %Save Y
            idY = numel(app.data_struct)+1;
            app.data_struct(idY).Unit = 'm';
            app.data_struct(idY).Name = 'Y';
            app.data_struct(idY).Data = Y;
            app.data_struct(idY).Frequency = '10'; 


            %Display
            app.InProgress.Text = strcat(app.filename,{' '},'was imported succesfully');
            app.ExportdatatotheworkspaceButton.Visible = 'on';
            app.ImportButton.Enable = 'off';
            
        end

        % Close request function: DataVisu
        function DataVisuCloseRequest(app, event)
            delete(app.Plot_app)
            delete(app.appG_force)
            delete(app.appCornering)
            delete(app.appVital)
            delete(app.appBraking)
            delete(app.appTemps)
            delete(app.appGrip)
            delete(app.appRoll)
            delete(app.appDampers)
            delete(app.appTrackplot)
            delete(app.appXY)
            delete(app.appFC)
            delete(app)
        end

        % Menu selected function: tempsdistanceMenu
        function tempsdistanceMenuSelected(app, event)
            app.Plot_app = Plot_Temps_Dist(app);
        end

        % Menu selected function: GForces
        function GForcesMenuSelected(app, event)
            app.appG_force = Gforce(app);
        end

        % Menu selected function: CorneringMenu
        function CorneringMenuSelected(app, event)
            app.appCornering = Cornering(app);
        end

        % Button pushed function: ExportdatatotheworkspaceButton
        function ExportdatatotheworkspaceButtonPushed(app, event)
            for i=1:numel(app.data_struct) %On parcour toute la tructure
                if ~isempty(app.data_struct(i).Name) % si c'est non vide (entre 50 et 100 par exemple)
                    assignin('base',app.data_struct(i).Name,app.data_struct(i).Data);
                end
            end
            assignin('base',"data_struct",app.data_struct);
        end

        % Menu selected function: VitalFunctionsMenu
        function VitalFunctionsMenuSelected(app, event)
            app.appVital = Vital(app);
        end

        % Menu selected function: BrakingMenu
        function BrakingMenuSelected(app, event)
            app.appBraking = Braking(app);
        end

        % Menu selected function: LapTimesMenu
        function LapTimesMenuSelected(app, event)
            app.appTemps = Temps(app);
        end

        % Menu selected function: GripfactorsMenu
        function GripfactorsMenuSelected(app, event)
            app.appGrip = GripFactor(app);
        end

        % Menu selected function: DampersMenu
        function DampersMenuSelected(app, event)
            app.appDampers = Dampers(app);
        end

        % Menu selected function: RollMenu
        function RollMenuSelected(app, event)
            app.appRoll = Roll_Gradient(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             [file,path] =  uigetfile('*.log','Select a log file');
              figure(app.DataVisu);
             if file ~= 0 
                 if strcmp('.log',file(end-3:end))
                    app.filename = fullfile(path,file);
                    app.ImportButton.Enable = 'on';
                    app.FilenameLabel.Text = strcat('File name :',{' '},file);
                    app.InProgress.Text = strcat(app.filename,{' '},' is ready for importation');
                 else
                     app.InProgress.text = 'You have to import a log file';
                 end
             end
        end

        % Menu selected function: DisplayontrackMenu
        function DisplayontrackMenuSelected(app, event)
            app.appTrackplot = Track_plot(app);
        end

        % Menu selected function: XYplotMenu
        function XYMenuSelected(app, event)
            app.appXY = XY(app);
        end

        % Menu selected function: FuelcomsuptionMenu
        function FuelcomsuptionMenuSelected(app, event)
            app.appFC = FuelC(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataVisu = uifigure('Visible', 'off');
            app.DataVisu.Name = 'UI Figure';
            app.DataVisu.CloseRequestFcn = createCallbackFcn(app, @DataVisuCloseRequest, true);
            app.DisplayMenu = uimenu(app.DataVisu);
            app.DisplayMenu.Text = 'Display';
            app.tempsdistanceMenu = uimenu(app.DisplayMenu);
            app.tempsdistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @tempsdistanceMenuSelected, true);
            app.tempsdistanceMenu.Text = '/temps,distance';
            app.DisplayontrackMenu = uimenu(app.DisplayMenu);
            app.DisplayontrackMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayontrackMenuSelected, true);
            app.DisplayontrackMenu.Text = 'Display on track';
            app.XYplotMenu = uimenu(app.DisplayMenu);
            app.XYplotMenu.MenuSelectedFcn = createCallbackFcn(app, @XYMenuSelected, true);
            app.XYplotMenu.Text = 'X-Y plot';
            app.AnalysisMenu = uimenu(app.DataVisu);
            app.AnalysisMenu.Text = 'Analysis';
            app.GForces = uimenu(app.AnalysisMenu);
            app.GForces.MenuSelectedFcn = createCallbackFcn(app, @GForcesMenuSelected, true);
            app.GForces.Text = 'G-Forces';
            app.CorneringMenu = uimenu(app.AnalysisMenu);
            app.CorneringMenu.MenuSelectedFcn = createCallbackFcn(app, @CorneringMenuSelected, true);
            app.CorneringMenu.Text = 'Cornering';
            app.VitalFunctionsMenu = uimenu(app.AnalysisMenu);
            app.VitalFunctionsMenu.MenuSelectedFcn = createCallbackFcn(app, @VitalFunctionsMenuSelected, true);
            app.VitalFunctionsMenu.Text = 'Vital Functions';
            app.BrakingMenu = uimenu(app.AnalysisMenu);
            app.BrakingMenu.MenuSelectedFcn = createCallbackFcn(app, @BrakingMenuSelected, true);
            app.BrakingMenu.Text = 'Braking';
            app.GripfactorsMenu = uimenu(app.AnalysisMenu);
            app.GripfactorsMenu.MenuSelectedFcn = createCallbackFcn(app, @GripfactorsMenuSelected, true);
            app.GripfactorsMenu.Text = 'Grip factors';
            app.DampersMenu = uimenu(app.AnalysisMenu);
            app.DampersMenu.MenuSelectedFcn = createCallbackFcn(app, @DampersMenuSelected, true);
            app.DampersMenu.Text = 'Dampers';
            app.RollMenu = uimenu(app.AnalysisMenu);
            app.RollMenu.MenuSelectedFcn = createCallbackFcn(app, @RollMenuSelected, true);
            app.RollMenu.Text = 'Roll ';
            app.FuelcomsuptionMenu = uimenu(app.AnalysisMenu);
            app.FuelcomsuptionMenu.MenuSelectedFcn = createCallbackFcn(app, @FuelcomsuptionMenuSelected, true);
            app.FuelcomsuptionMenu.Text = 'Fuel comsuption';
            app.PiloteMenu = uimenu(app.DataVisu);
            app.PiloteMenu.Text = 'Pilote';
            app.LapTimesMenu = uimenu(app.PiloteMenu);
            app.LapTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @LapTimesMenuSelected, true);
            app.LapTimesMenu.Text = 'Lap Times';
            app.GPS = uiaxes(app.DataVisu);
            title(app.GPS, 'Coordonnées GPS')
            app.Duree = uilabel(app.DataVisu);
            app.Duree.Text = 'Run duration : ';
            app.Vmax = uilabel(app.DataVisu);
            app.Vmax.Text = 'Maximuml speed :';
            app.Gxmax = uilabel(app.DataVisu);
            app.Gxmax.Text = 'Maximum longitudinal acceleration :';
            app.Gxmin = uilabel(app.DataVisu);
            app.Gxmin.Text = 'Minimum longitudinale acceleration :';
            app.Gymax = uilabel(app.DataVisu);
            app.Gymax.Text = 'Maximum lateral acceleration :';
            app.ExportdatatotheworkspaceButton = uibutton(app.DataVisu, 'push');
            app.ExportdatatotheworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatatotheworkspaceButtonPushed, true);
            app.ExportdatatotheworkspaceButton.Text = 'Export data to the workspace';
            app.Ttour = uilabel(app.DataVisu);
            app.Ttour.Text = 'Best lap time :';
            app.NbTour = uilabel(app.DataVisu);
            app.NbTour.Text = 'Number of lap :';
            app.Image = uiimage(app.DataVisu);
            app.Image.ImageSource = 'logo.png';
            app.InProgress = uilabel(app.DataVisu);
            app.InProgress.Text = 'InProgress';
            app.ImportafilePanel = uipanel(app.DataVisu);
            app.ImportafilePanel.Title = 'Import a file';
            app.BrowseButton = uibutton(app.ImportafilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilenameLabel = uilabel(app.ImportafilePanel);
            app.FilenameLabel.Text = 'File name :';
            app.ImportButton = uibutton(app.ImportafilePanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            % Show the figure after all components are created
            app.DataVisu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datavisu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataVisu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataVisu)
        end
    end
end